classdef appSimIC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        TabGroup                matlab.ui.container.TabGroup       % Sinal ...
        TabSignal               matlab.ui.container.Tab            % Sinal ...
        PanSinEntr              matlab.ui.container.Panel          % Sinal ...
        Label3                  matlab.ui.control.Label            % Dist. ...
        NumDistAlvo             matlab.ui.control.NumericEditField % [80 300]
        Label4                  matlab.ui.control.Label            % Ângulo...
        NumAngAlvo              matlab.ui.control.NumericEditField % [-180 ...
        Label5                  matlab.ui.control.Label            % Dist. ...
        NumDistRuido            matlab.ui.control.NumericEditField % [80 300]
        Label6                  matlab.ui.control.Label            % Ângulo...
        NumAngRuido             matlab.ui.control.NumericEditField % [-180 ...
        Label7                  matlab.ui.control.Label            % SNR [ ...
        NumSNR                  matlab.ui.control.NumericEditField % [-Inf ...
        Label8                  matlab.ui.control.Label            % Ruído
        DropRuido               matlab.ui.control.DropDown         % RB, Wh...
        Label42                 matlab.ui.control.Label            % Sinal
        DropSignal              matlab.ui.control.DropDown         % RB, Wh...
        ToggleSwitch            matlab.ui.control.ToggleSwitch     % Esquer...
        axisSig                 matlab.ui.control.UIAxes          
        Panel9                  matlab.ui.container.Panel          % Panel
        ButPlaySinal            matlab.ui.control.Button           % Ouvir ...
        LabelLamp2              matlab.ui.control.Label            % Reprod...
        LampSignal              matlab.ui.control.Lamp            
        LabelKnob2              matlab.ui.control.Label            % Volume
        Knob2                   matlab.ui.control.Knob             % [0 100]
        Label43                 matlab.ui.control.Label            % *
        Label44                 matlab.ui.control.Label            % *
        Label45                 matlab.ui.control.Label            % Pos. Alvo
        Label46                 matlab.ui.control.Label            % Pos. R...
        TabPaci                 matlab.ui.container.Tab            % Paciente
        PanPac                  matlab.ui.container.Panel          % Propri...
        ButImportPac            matlab.ui.control.Button           % Import...
        LabelDropDown           matlab.ui.control.Label            % Estrat...
        DropStrat               matlab.ui.control.DropDown         % ACE, CIS
        LabelNumericEditField2  matlab.ui.control.Label            % Num má...
        Num_maxima              matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField3  matlab.ui.control.Label            % Taxa d...
        Num_taxa_est            matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField4  matlab.ui.control.Label            % Largur...
        Num_largura_pulso1      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField5  matlab.ui.control.Label            % IPG [ s ]
        Num_interphase_gap      matlab.ui.control.NumericEditField % [-Inf ...
        LabelDropDown2          matlab.ui.control.Label            % LGF
        DropLGF                 matlab.ui.control.DropDown         % 20, 30...
        ButMapping              matlab.ui.control.Button           % Mapeam...
        Label                   matlab.ui.control.Label            % Num. c...
        Num_num_canais          matlab.ui.control.NumericEditField % [8 22]
        Panel8                  matlab.ui.container.Panel          % Propri...
        List_pacientes          matlab.ui.control.ListBox          % Item 1...
        TabGroup2               matlab.ui.container.TabGroup       % Map 1,...
        Tab                     matlab.ui.container.Tab            % Map 1
        Num_M12                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M22                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M32                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M42                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M52                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M62                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M72                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M82                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M92                 matlab.ui.control.NumericEditField % [-Inf ...
        Num_M102                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M112                matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField   matlab.ui.control.Label            % 1
        Num_M11                 matlab.ui.control.NumericEditField % [-Inf ...
        Label20                 matlab.ui.control.Label            % 2
        Num_M21                 matlab.ui.control.NumericEditField % [-Inf ...
        Label21                 matlab.ui.control.Label            % 3
        Num_M31                 matlab.ui.control.NumericEditField % [-Inf ...
        Label22                 matlab.ui.control.Label            % 4
        Num_M41                 matlab.ui.control.NumericEditField % [-Inf ...
        Label23                 matlab.ui.control.Label            % 5
        Num_M51                 matlab.ui.control.NumericEditField % [-Inf ...
        Label24                 matlab.ui.control.Label            % 6
        Num_M61                 matlab.ui.control.NumericEditField % [-Inf ...
        Label25                 matlab.ui.control.Label            % 7
        Num_M71                 matlab.ui.control.NumericEditField % [-Inf ...
        Label26                 matlab.ui.control.Label            % 9
        Num_M91                 matlab.ui.control.NumericEditField % [-Inf ...
        Label27                 matlab.ui.control.Label            % 8
        Num_M81                 matlab.ui.control.NumericEditField % [-Inf ...
        Label28                 matlab.ui.control.Label            % 10
        Num_M101                matlab.ui.control.NumericEditField % [-Inf ...
        Label29                 matlab.ui.control.Label            % 11
        Num_M111                matlab.ui.control.NumericEditField % [-Inf ...
        Tab2                    matlab.ui.container.Tab            % Map 2
        Num_M122                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M132                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M142                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M152                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M162                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M172                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M182                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M192                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M202                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M212                matlab.ui.control.NumericEditField % [-Inf ...
        Num_M222                matlab.ui.control.NumericEditField % [-Inf ...
        Label30                 matlab.ui.control.Label            % 12
        Num_M121                matlab.ui.control.NumericEditField % [-Inf ...
        Label31                 matlab.ui.control.Label            % 13
        Num_M131                matlab.ui.control.NumericEditField % [-Inf ...
        Label32                 matlab.ui.control.Label            % 14
        Num_M141                matlab.ui.control.NumericEditField % [-Inf ...
        Label33                 matlab.ui.control.Label            % 15
        Num_M151                matlab.ui.control.NumericEditField % [-Inf ...
        Label34                 matlab.ui.control.Label            % 16
        Num_M161                matlab.ui.control.NumericEditField % [-Inf ...
        Label35                 matlab.ui.control.Label            % 17
        Num_M171                matlab.ui.control.NumericEditField % [-Inf ...
        Label36                 matlab.ui.control.Label            % 18
        Num_M181                matlab.ui.control.NumericEditField % [-Inf ...
        Label37                 matlab.ui.control.Label            % 20
        Num_M191                matlab.ui.control.NumericEditField % [-Inf ...
        Label38                 matlab.ui.control.Label            % 19
        Num_M201                matlab.ui.control.NumericEditField % [-Inf ...
        Label39                 matlab.ui.control.Label            % 21
        Num_M211                matlab.ui.control.NumericEditField % [-Inf ...
        Label40                 matlab.ui.control.Label            % 22
        Num_M221                matlab.ui.control.NumericEditField % [-Inf ...
        TabProcess              matlab.ui.container.Tab            % Proces...
        PanProcess              matlab.ui.container.Panel          % Proces...
        Label9                  matlab.ui.control.Label            % Banco ...
        DropBancFilt            matlab.ui.control.DropDown         % ERB
        Label10                 matlab.ui.control.Label            % Extr. ...
        DropExtrEnvolt          matlab.ui.control.DropDown         % Hilbert
        Label11                 matlab.ui.control.Label            % Freq. ...
        NumCutFPB               matlab.ui.control.NumericEditField % [-Inf ...
        Label12                 matlab.ui.control.Label            % Ordem FPB
        NumOrdFPB               matlab.ui.control.NumericEditField % [-Inf ...
        ButSimProcess           matlab.ui.control.Button           % Simula...
        LabelLamp               matlab.ui.control.Label            % Status
        LampProcess             matlab.ui.control.Lamp            
        LabelTextArea           matlab.ui.control.Label            % Propri...
        TextArea                matlab.ui.control.TextArea        
        Label47                 matlab.ui.control.Label            % Propri...
        TextArea2               matlab.ui.control.TextArea        
        TabMNA                  matlab.ui.container.Tab            % Modelo...
        PanMNA                  matlab.ui.container.Panel          % Modelo...
        Label13                 matlab.ui.control.Label            % Elétrico
        DropEletrico            matlab.ui.control.DropDown         % LIF
        ButPropNeuro            matlab.ui.control.Button           % Propri...
        Label14                 matlab.ui.control.Label            % Acústico
        DropAcustico            matlab.ui.control.DropDown         % Zilany
        LabelNumericEditField14 matlab.ui.control.Label            % Num. n...
        NumNNeuro               matlab.ui.control.NumericEditField % [-Inf ...
        Label19                 matlab.ui.control.Label            % Espalh...
        DropEspalha             matlab.ui.control.DropDown         % Gauss,...
        Button13                matlab.ui.control.Button           % Simula...
        TabVoc                  matlab.ui.container.Tab            % Vocoders
        Panel6                  matlab.ui.container.Panel          % Compar...
        LabelKnob               matlab.ui.control.Label            % Volume
        Knob                    matlab.ui.control.Knob             % [0 100]
        ButtonGroup             matlab.ui.container.ButtonGroup    % Sinais...
        ToggleButton            matlab.ui.control.ToggleButton     % Sinal ...
        ToggleButton2           matlab.ui.control.ToggleButton     % Vocode...
        ToggleButton3           matlab.ui.control.ToggleButton     % Neural...
        Button17                matlab.ui.control.Button           % Play
        Panel7                  matlab.ui.container.Panel          % Neural...
        Button14                matlab.ui.control.Button           % Propri...
        Button16                matlab.ui.control.Button           % Simula...
        StateButton             matlab.ui.control.StateButton      % State ...
        TabPlots                matlab.ui.container.Tab            % Plots
        UIAxes2                 matlab.ui.control.UIAxes           % Title
        Button18                matlab.ui.control.Button           % Eletro...
        Button19                matlab.ui.control.Button           % Neurog...
        Button20                matlab.ui.control.Button           % Espect...
        Tab3                    matlab.ui.container.Tab            % Help
        ButPlayRuido            matlab.ui.control.Button           % Ouvir ...
        ButPlayMescla           matlab.ui.control.Button           % Ouvir ...
    end

    
    properties (Access = public)
        T_Level % Description
        C_Level % Description
    end
    
    properties (Access = private)
        sim % Description
    end
    
    methods (Access = private)
        
        function results = circle(app)
            r=1;
            x=0;
            y=0;
            
            rA = app.NumDistAlvo.Value/100;
            rR = app.NumDistRuido.Value/100;
            thetaA = pi*app.NumAngAlvo.Value/180;
            thetaR = pi*app.NumAngRuido.Value/180;
            
            
            th = 0:pi/50:2*pi;
            xunit = r * cos(th) + x;
            yunit = r * sin(th) + y;
            
            xAlvo = rA*cos(-thetaA);
            yAlvo = rA*sin(-thetaA);
            xRuido = rR*cos(-thetaR);
            yRuido = rR*sin(-thetaR);
            
            
            plot(app.axisSig,xAlvo,yAlvo,'*')
            hold(app.axisSig, 'on')
            plot(app.axisSig,xRuido,yRuido,'*')
            
            plot(app.axisSig,0.8*xunit, 0.8*yunit,'k:');
            plot(app.axisSig,3*xunit, 3*yunit,'k--');
            xlim(app.axisSig, [-3.1, 3.1])
            ylim(app.axisSig, [-3.1, 3.1])
            %text(app.axisSig,-0.09,-0,'\subset','fontsize',15)
            %text(app.axisSig, 0.03,-0,'\supset','fontsize',15)
            plot(app.axisSig,[0.1,0.25,0.1],[-0.1,0,0.1],'k')
            plot(app.axisSig,xunit/7, yunit/7,'k');
            % text(app.axisSig,-0.5,0,'O','fontsize',24)
            %legend(app.axisSig,'Ouvinte','Posição alvo', 'Posição ruído',...
            %  'location','EastOutside')
            hold(app.axisSig, 'off')
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd
            addpath(genpath('./funcoes'))
            addpath(genpath('./sinais_entrada'))
            addpath(genpath('./dados_pacientes'))
            Pacientes = dir([pwd,'/dados_pacientes/*.dat']);
            app.List_pacientes.Items = cellstr({Pacientes.name});
            
            Sinais = dir([pwd,'/sinais_entrada/*.wav']);
            app.DropSignal.Items = cellstr({Sinais.name});
            
            Ruidos = dir([pwd,'/sinais_entrada/ruidos/*.wav']);
            app.DropRuido.Items = cellstr({Ruidos.name; 'sem_ruido'});
            
            circle(app)
            ButImportPacButtonPushed(app)
            
            app.sim = CsimIC(app.List_pacientes.Value,'asa.wav');
        end

        % ButImportPac button pushed function
        function ButImportPacButtonPushed(app)
            
            % Carrega dados paciente
            fileName = app.List_pacientes.Value;
            % Atualiza campos da GUI
            app.Num_num_canais.Value = max(dlmread(fileName,'\t',[15 0 36 0]));
            app.Num_maxima.Value = dlmread(fileName,'\t',[3 1 3 1]);
            app.Num_interphase_gap.Value = mean(dlmread(fileName,'\t',[4 1 4 1]))*1e-6;
            app.Num_largura_pulso1.Value = mean(dlmread(fileName,'\t',[15 7 36 7]))*1e-6;
            % app.DropLGF = (dlmread(fileName,'\t',[7 1 7 1]));
            
            % obj.largura_pulso2 = mean(dlmread(fileName,'\t',[15 7 36 7]))*1e-6;
            T_SPL = dlmread(fileName,'\t',[8 1 8 1]);
            C_SPL = dlmread(fileName,'\t',[9 1 9 1]);
            app.T_Level = dlmread(fileName,'\t',[15 3 36 3]);
            app.C_Level = dlmread(fileName,'\t',[15 4 36 4]);
            
            % Atribui vetores de T e C level aos campos de mapeamento da GUI
            for iCh = 1:app.Num_num_canais.Value;
                app.(strcat('Num_M',num2str(iCh),num2str(1))).Value = app.T_Level(iCh,1);
                app.(strcat('Num_M',num2str(iCh),num2str(2))).Value = app.C_Level(iCh,1);
            end
            
            %             obj.inf_freq = dlmread(fileName,'\t',[15 9 36 9]);
            %             obj.sup_freq = dlmread(fileName,'\t',[15 10 36 10]);
            %
            %
            
        end

        % ButSimProcess button pushed function
        function ButSimProcessButtonPushed(app)
            app.LampProcess.Color = 'y';
            
            
            % selecao de alguns parametros
            app.sim.num_canais = app.Num_num_canais.Value;
            app.sim.maxima = app.Num_maxima.Value;
            app.sim.taxa_est = app.Num_taxa_est.Value;
            
            % processamento do implante
            app.sim.cis_ace();
            app.LampProcess.Color = 'g';
        end

        % NumDistAlvo value changed function
        function NumDistAlvoValueChanged(app)
            value = app.NumDistAlvo.Value;
            circle(app)
            app.sim.dist_alvo = value;

        end

        % NumDistRuido value changed function
        function NumDistRuidoValueChanged(app)
            value = app.NumDistRuido.Value;
            circle(app)
            
            app.sim.dist_ruido = value;

        end

        % NumAngAlvo value changed function
        function NumAngAlvoValueChanged(app)
            value = app.NumAngAlvo.Value;
            circle(app)
            
            app.sim.azmt_alvo = value;
        end

        % NumAngRuido value changed function
        function NumAngRuidoValueChanged(app)
            value = app.NumAngRuido.Value;
            circle(app)
            
            app.sim.azmt_ruido = value;
        end

        % ButGeraSinal button pushed function
        function ButGeraSinalButtonPushed(app)
            app.ButPlaySinal.Enable='on';
            
            app.sim.mesclar
            
            app.LampSignal.Color = 'g';
        end

        % ToggleSwitch value changed function
        function ToggleSwitchValueChanged(app)
            value = app.ToggleSwitch.Value;
            if strcmp('Esquerdo',value)
                app.sim.lado = 'esquerda';
            else
                app.sim.lado = 'direita';
            end
        end

        % DropRuido value changed function
        function DropRuidoValueChanged(app, event)
            value = app.DropRuido.Value;
            app.sim.arqR = value;
            
            if strcmp(value,'sem_ruido')
                app.NumSNR.Value = 9999;
                app.NumSNR.Enable = 'off';
            else
                app.NumSNR.Enable = 'on';
            end
        end

        % DropSignal value changed function
        function DropSignalValueChanged(app, event)
            value = app.DropSignal.Value;
            app.sim.arqX = value;
        end

        % ButPlaySinal button pushed function
        function ButPlaySinalButtonPushed(app)
            app.LampSignal.Visible = 'on';
            app.sim.reproduz_audio('alvo');
            app.LampSignal.Visible = 'off';
            
        end

        % ButPlayRuido button pushed function
        function ButPlayRuidoButtonPushed(app)
            app.LampSignal.Visible = 'on';
            app.sim.reproduz_audio('ruido');
            app.LampSignal.Visible = 'off';
                        
        end

        % ButPlayMescla button pushed function
        function ButPlayMesclaButtonPushed(app)
            app.LampSignal.Visible = 'on';
            app.sim.reproduz_audio('mescla');
            app.LampSignal.Visible = 'off';
                        
        end

        % NumSNR value changed function
        function NumSNRValueChanged(app)
            value = app.NumSNR.Value;
            app.sim.SNRdB = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.TabSignal = uitab(app.TabGroup);
            app.TabSignal.Units = 'pixels';
            app.TabSignal.Title = 'Sinal de entrada';
            app.PanSinEntr = uipanel(app.TabSignal);
            app.PanSinEntr.Title = 'Sinal de entrada';
            app.PanSinEntr.Units = 'pixels';
            app.Label3 = uilabel(app.PanSinEntr);
            app.Label3.Text = 'Dist. alvo [ cm ]';
            app.NumDistAlvo = uieditfield(app.PanSinEntr, 'numeric');
            app.NumDistAlvo.ValueChangedFcn = createCallbackFcn(app, @NumDistAlvoValueChanged);
            app.NumDistAlvo.Limits = [80 300];
            app.NumDistAlvo.Value = 80;
            app.Label4 = uilabel(app.PanSinEntr);
            app.Label4.Text = 'Ângulo alvo [ º ]';
            app.NumAngAlvo = uieditfield(app.PanSinEntr, 'numeric');
            app.NumAngAlvo.ValueChangedFcn = createCallbackFcn(app, @NumAngAlvoValueChanged);
            app.NumAngAlvo.Limits = [-180 180];
            app.NumAngAlvo.Value = 30;
            app.Label5 = uilabel(app.PanSinEntr);
            app.Label5.Text = 'Dist. ruído [ cm ]';
            app.NumDistRuido = uieditfield(app.PanSinEntr, 'numeric');
            app.NumDistRuido.ValueChangedFcn = createCallbackFcn(app, @NumDistRuidoValueChanged);
            app.NumDistRuido.Limits = [80 300];
            app.NumDistRuido.Value = 300;
            app.Label6 = uilabel(app.PanSinEntr);
            app.Label6.Text = 'Ângulo ruído [ º ]';
            app.NumAngRuido = uieditfield(app.PanSinEntr, 'numeric');
            app.NumAngRuido.ValueChangedFcn = createCallbackFcn(app, @NumAngRuidoValueChanged);
            app.NumAngRuido.Limits = [-180 180];
            app.NumAngRuido.Value = 10;
            app.Label7 = uilabel(app.PanSinEntr);
            app.Label7.Text = 'SNR [ dB ]';
            app.NumSNR = uieditfield(app.PanSinEntr, 'numeric');
            app.NumSNR.ValueChangedFcn = createCallbackFcn(app, @NumSNRValueChanged);
            app.NumSNR.Value = 10;
            app.Label8 = uilabel(app.PanSinEntr);
            app.Label8.Text = 'Ruído';
            app.DropRuido = uidropdown(app.PanSinEntr);
            app.DropRuido.Items = {'RB', 'White', 'Tráfego'};
            app.DropRuido.ValueChangedFcn = createCallbackFcn(app, @DropRuidoValueChanged, true);
            app.DropRuido.Value = 'RB';
            app.Label42 = uilabel(app.PanSinEntr);
            app.Label42.Text = 'Sinal';
            app.DropSignal = uidropdown(app.PanSinEntr);
            app.DropSignal.Items = {'RB', 'White', 'Tráfego'};
            app.DropSignal.ValueChangedFcn = createCallbackFcn(app, @DropSignalValueChanged, true);
            app.DropSignal.Value = 'RB';
            app.axisSig = uiaxes(app.TabSignal);
            app.Panel9 = uipanel(app.TabSignal);
            app.Panel9.Title = 'Panel';
            app.Panel9.Units = 'pixels';
            app.ButPlaySinal = uibutton(app.Panel9, 'push');
            app.ButPlaySinal.ButtonPushedFcn = createCallbackFcn(app, @ButPlaySinalButtonPushed);
            app.ButPlaySinal.Icon = 'playIcon.jpg';
            app.ButPlaySinal.Text = 'Ouvir sinal';
            app.LabelLamp2 = uilabel(app.Panel9);
            app.LabelLamp2.Enable = 'off';
            app.LabelLamp2.Text = 'Reproduzindo';
            app.LampSignal = uilamp(app.Panel9);
            app.LampSignal.Visible = 'off';
            app.LabelKnob2 = uilabel(app.Panel9);
            app.LabelKnob2.Enable = 'off';
            app.LabelKnob2.Text = 'Volume';
            app.Knob2 = uiknob(app.Panel9, 'continuous');
            app.Knob2.MajorTicks = [0 20 40 60 80 100];
            app.Knob2.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.Knob2.Enable = 'off';
            app.ButPlayRuido = uibutton(app.Panel9, 'push');
            app.ButPlayRuido.ButtonPushedFcn = createCallbackFcn(app, @ButPlayRuidoButtonPushed);
            app.ButPlayRuido.Icon = 'playIcon.jpg';
            app.ButPlayRuido.Text = 'Ouvir ruído';
            app.ButPlayMescla = uibutton(app.Panel9, 'push');
            app.ButPlayMescla.ButtonPushedFcn = createCallbackFcn(app, @ButPlayMesclaButtonPushed);
            app.ButPlayMescla.Icon = 'playIcon.jpg';
            app.ButPlayMescla.Text = 'Ouvir mescla';
            app.ToggleSwitch = uiswitch(app.Panel9, 'toggle');
            app.ToggleSwitch.Items = {'Esquerdo', 'Direito'};
            app.ToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged);
            app.ToggleSwitch.Value = 'Esquerdo';
            app.Label43 = uilabel(app.TabSignal);
            app.Label43.Text = '*';
            app.Label44 = uilabel(app.TabSignal);
            app.Label44.Text = '*';
            app.Label45 = uilabel(app.TabSignal);
            app.Label45.Text = 'Pos. Alvo';
            app.Label46 = uilabel(app.TabSignal);
            app.Label46.Text = 'Pos. Ruído';
            app.TabPaci = uitab(app.TabGroup);
            app.TabPaci.Units = 'pixels';
            app.TabPaci.Title = 'Paciente';
            app.PanPac = uipanel(app.TabPaci);
            app.PanPac.Title = 'Propriedades';
            app.PanPac.Units = 'pixels';
            app.ButImportPac = uibutton(app.PanPac, 'push');
            app.ButImportPac.ButtonPushedFcn = createCallbackFcn(app, @ButImportPacButtonPushed);
            app.ButImportPac.Text = 'Importar paciente';
            app.LabelDropDown = uilabel(app.PanPac);
            app.LabelDropDown.Text = 'Estratégia';
            app.DropStrat = uidropdown(app.PanPac);
            app.DropStrat.Items = {'ACE', 'CIS'};
            app.DropStrat.Value = 'ACE';
            app.LabelNumericEditField2 = uilabel(app.PanPac);
            app.LabelNumericEditField2.Text = 'Num máximas';
            app.Num_maxima = uieditfield(app.PanPac, 'numeric');
            app.Num_maxima.Value = 10;
            app.LabelNumericEditField3 = uilabel(app.PanPac);
            app.LabelNumericEditField3.Text = 'Taxa de estímulo';
            app.Num_taxa_est = uieditfield(app.PanPac, 'numeric');
            app.Num_taxa_est.Value = 800;
            app.LabelNumericEditField4 = uilabel(app.PanPac);
            app.LabelNumericEditField4.Text = 'Largura pulso [ s ]';
            app.Num_largura_pulso1 = uieditfield(app.PanPac, 'numeric');
            app.Num_largura_pulso1.Value = 2.5e-05;
            app.LabelNumericEditField5 = uilabel(app.PanPac);
            app.LabelNumericEditField5.Text = 'IPG [ s ]';
            app.Num_interphase_gap = uieditfield(app.PanPac, 'numeric');
            app.Num_interphase_gap.Value = 8e-06;
            app.LabelDropDown2 = uilabel(app.PanPac);
            app.LabelDropDown2.Text = 'LGF';
            app.DropLGF = uidropdown(app.PanPac);
            app.DropLGF.Items = {'20', '30', '40', '50'};
            app.DropLGF.Editable = 'on';
            app.DropLGF.Value = '20';
            app.ButMapping = uibutton(app.PanPac, 'push');
            app.ButMapping.Text = 'Mapeamento';
            app.Label = uilabel(app.PanPac);
            app.Label.Text = 'Num. canais';
            app.Num_num_canais = uieditfield(app.PanPac, 'numeric');
            app.Num_num_canais.Limits = [8 22];
            app.Num_num_canais.Value = 22;
            app.Panel8 = uipanel(app.TabPaci);
            app.Panel8.Title = 'Propriedades';
            app.Panel8.Units = 'pixels';
            app.List_pacientes = uilistbox(app.Panel8);
            app.TabGroup2 = uitabgroup(app.TabPaci);
            app.TabGroup2.Units = 'pixels';
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Map 1';
            app.Num_M12 = uieditfield(app.Tab, 'numeric');
            app.Num_M12.ValueDisplayFormat = '%.0f';
            app.Num_M22 = uieditfield(app.Tab, 'numeric');
            app.Num_M22.ValueDisplayFormat = '%.0f';
            app.Num_M32 = uieditfield(app.Tab, 'numeric');
            app.Num_M32.ValueDisplayFormat = '%.0f';
            app.Num_M42 = uieditfield(app.Tab, 'numeric');
            app.Num_M42.ValueDisplayFormat = '%.0f';
            app.Num_M52 = uieditfield(app.Tab, 'numeric');
            app.Num_M52.ValueDisplayFormat = '%.0f';
            app.Num_M62 = uieditfield(app.Tab, 'numeric');
            app.Num_M62.ValueDisplayFormat = '%.0f';
            app.Num_M72 = uieditfield(app.Tab, 'numeric');
            app.Num_M72.ValueDisplayFormat = '%.0f';
            app.Num_M82 = uieditfield(app.Tab, 'numeric');
            app.Num_M82.ValueDisplayFormat = '%.0f';
            app.Num_M92 = uieditfield(app.Tab, 'numeric');
            app.Num_M92.ValueDisplayFormat = '%.0f';
            app.Num_M102 = uieditfield(app.Tab, 'numeric');
            app.Num_M102.ValueDisplayFormat = '%.0f';
            app.Num_M112 = uieditfield(app.Tab, 'numeric');
            app.Num_M112.ValueDisplayFormat = '%.0f';
            app.LabelNumericEditField = uilabel(app.Tab);
            app.LabelNumericEditField.Text = '1';
            app.Num_M11 = uieditfield(app.Tab, 'numeric');
            app.Label20 = uilabel(app.Tab);
            app.Label20.Text = '2';
            app.Num_M21 = uieditfield(app.Tab, 'numeric');
            app.Label21 = uilabel(app.Tab);
            app.Label21.Text = '3';
            app.Num_M31 = uieditfield(app.Tab, 'numeric');
            app.Label22 = uilabel(app.Tab);
            app.Label22.Text = '4';
            app.Num_M41 = uieditfield(app.Tab, 'numeric');
            app.Label23 = uilabel(app.Tab);
            app.Label23.Text = '5';
            app.Num_M51 = uieditfield(app.Tab, 'numeric');
            app.Label24 = uilabel(app.Tab);
            app.Label24.Text = '6';
            app.Num_M61 = uieditfield(app.Tab, 'numeric');
            app.Label25 = uilabel(app.Tab);
            app.Label25.Text = '7';
            app.Num_M71 = uieditfield(app.Tab, 'numeric');
            app.Label26 = uilabel(app.Tab);
            app.Label26.Text = '9';
            app.Num_M91 = uieditfield(app.Tab, 'numeric');
            app.Label27 = uilabel(app.Tab);
            app.Label27.Text = '8';
            app.Num_M81 = uieditfield(app.Tab, 'numeric');
            app.Label28 = uilabel(app.Tab);
            app.Label28.Text = '10';
            app.Num_M101 = uieditfield(app.Tab, 'numeric');
            app.Label29 = uilabel(app.Tab);
            app.Label29.Text = '11';
            app.Num_M111 = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Map 2';
            app.Num_M122 = uieditfield(app.Tab2, 'numeric');
            app.Num_M132 = uieditfield(app.Tab2, 'numeric');
            app.Num_M142 = uieditfield(app.Tab2, 'numeric');
            app.Num_M152 = uieditfield(app.Tab2, 'numeric');
            app.Num_M162 = uieditfield(app.Tab2, 'numeric');
            app.Num_M172 = uieditfield(app.Tab2, 'numeric');
            app.Num_M182 = uieditfield(app.Tab2, 'numeric');
            app.Num_M192 = uieditfield(app.Tab2, 'numeric');
            app.Num_M202 = uieditfield(app.Tab2, 'numeric');
            app.Num_M212 = uieditfield(app.Tab2, 'numeric');
            app.Num_M222 = uieditfield(app.Tab2, 'numeric');
            app.Label30 = uilabel(app.Tab2);
            app.Label30.Text = '12';
            app.Num_M121 = uieditfield(app.Tab2, 'numeric');
            app.Label31 = uilabel(app.Tab2);
            app.Label31.Text = '13';
            app.Num_M131 = uieditfield(app.Tab2, 'numeric');
            app.Label32 = uilabel(app.Tab2);
            app.Label32.Text = '14';
            app.Num_M141 = uieditfield(app.Tab2, 'numeric');
            app.Label33 = uilabel(app.Tab2);
            app.Label33.Text = '15';
            app.Num_M151 = uieditfield(app.Tab2, 'numeric');
            app.Label34 = uilabel(app.Tab2);
            app.Label34.Text = '16';
            app.Num_M161 = uieditfield(app.Tab2, 'numeric');
            app.Label35 = uilabel(app.Tab2);
            app.Label35.Text = '17';
            app.Num_M171 = uieditfield(app.Tab2, 'numeric');
            app.Label36 = uilabel(app.Tab2);
            app.Label36.Text = '18';
            app.Num_M181 = uieditfield(app.Tab2, 'numeric');
            app.Label37 = uilabel(app.Tab2);
            app.Label37.Text = '20';
            app.Num_M191 = uieditfield(app.Tab2, 'numeric');
            app.Label38 = uilabel(app.Tab2);
            app.Label38.Text = '19';
            app.Num_M201 = uieditfield(app.Tab2, 'numeric');
            app.Label39 = uilabel(app.Tab2);
            app.Label39.Text = '21';
            app.Num_M211 = uieditfield(app.Tab2, 'numeric');
            app.Label40 = uilabel(app.Tab2);
            app.Label40.Text = '22';
            app.Num_M221 = uieditfield(app.Tab2, 'numeric');
            app.TabProcess = uitab(app.TabGroup);
            app.TabProcess.Units = 'pixels';
            app.TabProcess.Title = 'Processador';
            app.PanProcess = uipanel(app.TabProcess);
            app.PanProcess.Title = 'Processador';
            app.PanProcess.Units = 'pixels';
            app.Label9 = uilabel(app.PanProcess);
            app.Label9.Text = 'Banco de filtros';
            app.DropBancFilt = uidropdown(app.PanProcess);
            app.DropBancFilt.Items = {'ERB'};
            app.DropBancFilt.Value = 'ERB';
            app.Label10 = uilabel(app.PanProcess);
            app.Label10.Text = 'Extr. envoltória';
            app.DropExtrEnvolt = uidropdown(app.PanProcess);
            app.DropExtrEnvolt.Items = {'Hilbert'};
            app.DropExtrEnvolt.Value = 'Hilbert';
            app.Label11 = uilabel(app.PanProcess);
            app.Label11.Text = 'Freq. corte FPB [ Hz ]';
            app.NumCutFPB = uieditfield(app.PanProcess, 'numeric');
            app.NumCutFPB.Value = 200;
            app.Label12 = uilabel(app.PanProcess);
            app.Label12.Text = 'Ordem FPB';
            app.NumOrdFPB = uieditfield(app.PanProcess, 'numeric');
            app.NumOrdFPB.Value = 4;
            app.ButSimProcess = uibutton(app.PanProcess, 'push');
            app.ButSimProcess.ButtonPushedFcn = createCallbackFcn(app, @ButSimProcessButtonPushed);
            app.ButSimProcess.Text = 'Simular processamento';
            app.LabelLamp = uilabel(app.PanProcess);
            app.LabelLamp.Text = 'Status';
            app.LampProcess = uilamp(app.PanProcess);
            app.LabelTextArea = uilabel(app.TabProcess);
            app.LabelTextArea.Text = 'Propriedades Paciente';
            app.TextArea = uitextarea(app.TabProcess);
            app.Label47 = uilabel(app.TabProcess);
            app.Label47.Text = 'Propriedades Sinal';
            app.TextArea2 = uitextarea(app.TabProcess);
            app.TabMNA = uitab(app.TabGroup);
            app.TabMNA.Units = 'pixels';
            app.TabMNA.Title = 'Modelo Nervo auditivo';
            app.PanMNA = uipanel(app.TabMNA);
            app.PanMNA.Title = 'Modelo Nervo Auditivo';
            app.PanMNA.Units = 'pixels';
            app.Label13 = uilabel(app.PanMNA);
            app.Label13.Text = 'Elétrico';
            app.DropEletrico = uidropdown(app.PanMNA);
            app.DropEletrico.Items = {'LIF'};
            app.DropEletrico.Value = 'LIF';
            app.ButPropNeuro = uibutton(app.PanMNA, 'push');
            app.ButPropNeuro.Text = 'Propriedades neurônio';
            app.Label14 = uilabel(app.PanMNA);
            app.Label14.Text = 'Acústico';
            app.DropAcustico = uidropdown(app.PanMNA);
            app.DropAcustico.Items = {'Zilany'};
            app.DropAcustico.Value = 'Zilany';
            app.LabelNumericEditField14 = uilabel(app.PanMNA);
            app.LabelNumericEditField14.Text = 'Num. neurônios';
            app.NumNNeuro = uieditfield(app.PanMNA, 'numeric');
            app.NumNNeuro.Value = 100;
            app.Label19 = uilabel(app.PanMNA);
            app.Label19.Text = 'Espalhamento';
            app.DropEspalha = uidropdown(app.PanMNA);
            app.DropEspalha.Items = {'Gauss', 'CIS'};
            app.DropEspalha.Value = 'Gauss';
            app.Button13 = uibutton(app.PanMNA, 'push');
            app.Button13.Text = 'Simular modelo N.A.';
            app.TabVoc = uitab(app.TabGroup);
            app.TabVoc.Units = 'pixels';
            app.TabVoc.Title = 'Vocoders';
            app.Panel6 = uipanel(app.TabVoc);
            app.Panel6.Title = 'Comparações subjetivas';
            app.Panel6.Units = 'pixels';
            app.LabelKnob = uilabel(app.Panel6);
            app.LabelKnob.Text = 'Volume';
            app.Knob = uiknob(app.Panel6, 'continuous');
            app.ButtonGroup = uibuttongroup(app.Panel6);
            app.ButtonGroup.Title = 'Sinais audíveis';
            app.ButtonGroup.Units = 'pixels';
            app.ToggleButton = uitogglebutton(app.ButtonGroup);
            app.ToggleButton.Text = 'Sinal de entrada';
            app.ToggleButton.Value = true;
            app.ToggleButton2 = uitogglebutton(app.ButtonGroup);
            app.ToggleButton2.Text = 'Vocoder tradicional';
            app.ToggleButton3 = uitogglebutton(app.ButtonGroup);
            app.ToggleButton3.Text = 'Neural vocoder';
            app.Button17 = uibutton(app.Panel6, 'push');
            app.Button17.Icon = 'playIcon.jpg';
            app.Button17.Text = 'Play';
            app.Panel7 = uipanel(app.TabVoc);
            app.Panel7.Title = 'Neural Vocoder';
            app.Panel7.Units = 'pixels';
            app.Button14 = uibutton(app.Panel7, 'push');
            app.Button14.Text = 'Propriedades N-vocoder';
            app.Button16 = uibutton(app.Panel7, 'push');
            app.Button16.Text = 'Simular N-vocoder';
            app.StateButton = uibutton(app.TabVoc, 'state');
            app.TabPlots = uitab(app.TabGroup);
            app.TabPlots.Units = 'pixels';
            app.TabPlots.Title = 'Plots';
            app.UIAxes2 = uiaxes(app.TabPlots);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.Button18 = uibutton(app.TabPlots, 'push');
            app.Button18.Text = 'Eletrodograma';
            app.Button19 = uibutton(app.TabPlots, 'push');
            app.Button19.Text = 'Neurograma';
            app.Button20 = uibutton(app.TabPlots, 'push');
            app.Button20.Text = 'Espectrograma';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appSimIC()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end